classdef udp_communication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Button         matlab.ui.control.StateButton
        GraphicsPanel  matlab.ui.container.Panel
        UIAxes3        matlab.ui.control.UIAxes
        UIAxes         matlab.ui.control.UIAxes
        UIAxes4        matlab.ui.control.UIAxes
        UIAxes2        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        data % Description
        time_stamp
        udp_p
    end
    
    methods (Access = private)
        
        function readUDPData(app, src, ~)
            disp("Data received:");
            read_data = read(src,1,"string");
            disp(read_data.Data + newline);
            struct_data = jsondecode(read_data.Data);
            new_data = [struct_data.strain_1; struct_data.strain_2; struct_data.strain_3; struct_data.temp; uint64(struct_data.timestamp)];
            app.data = [app.data new_data];
        end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            if value
                app.udp_p = udpport("datagram", "LocalHost", "127.0.0.1", "LocalPort", 9870);
                disp('udp connected');
                configureCallback(app.udp_p,"datagram", 1 ,@(src, evnt)readUDPData(app, src, evnt));
            else
                disp("off")
                configureCallback(app.udp_p,"off")
                flush(app.udp_p,"output");
                app.udp_p.delete();
                disp('udp disconnected');
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GraphicsPanel = uipanel(app.UIFigure);
            app.GraphicsPanel.Title = 'Graphics';
            app.UIAxes2 = uiaxes(app.GraphicsPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.GraphicsPanel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes = uiaxes(app.GraphicsPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.GraphicsPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Button';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = udp_communication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end